classdef image_processor_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        enternoisevariancevalueLabel  matlab.ui.control.Label
        BrowseImageButton             matlab.ui.control.Button
        SaveProcessedImageButton      matlab.ui.control.Button
        ProcessedimageLabel           matlab.ui.control.Label
        OrignalimageLabel             matlab.ui.control.Label
        EdgeSharpeningButton          matlab.ui.control.Button
        EdgesharpeningSlider          matlab.ui.control.Slider
        EditField                     matlab.ui.control.EditField
        GaussianNoiseButton           matlab.ui.control.Button
        BarrelDistortionButton        matlab.ui.control.Button
        BrightnessIncreaseButton      matlab.ui.control.Button
        ConvertToGreyscaleButton      matlab.ui.control.Button
        UIAxes_2                      matlab.ui.control.UIAxes
        UIAxes                        matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        original_image % Variable used to store the original image
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: BrowseImageButton
        function BrowseImageButtonPushed(app, event)
            [file, path] = uigetfile({'*.png;*.jpg;*.jpeg;*.bmp;*.tiff', 'Image Files'}, 'Select an Image');

            % Reopening app window because it minimizes after image upload
            figure(app.UIFigure);

            if isequal(file, 0)
                msgbox('Image not found.', 'Error', 'Warning');
                return;
            end

            imgPath = fullfile(path, file);
            app.original_image = imread(imgPath);

             % Clearing the axes which holdes the processed image before
             % showing the uploaded image
            cla(app.UIAxes_2);
            
            % Displaying the original image
            imshow(app.original_image, 'Parent', app.UIAxes); 
        end

        % Button pushed function: ConvertToGreyscaleButton
        function ConvertToGreyscaleButtonPushed(app, event)
            if isempty(app.original_image)
                msgbox('No image loaded.', 'Error', 'Warning');
                return;
            end

            grayscale_image = rgb2gray(app.original_image);

            imshow(grayscale_image, 'Parent', app.UIAxes_2); 
        end

        % Button pushed function: BrightnessIncreaseButton
        function BrightnessIncreaseButtonPushed(app, event)
           if isempty(app.original_image)
                msgbox('No image loaded.', 'Error', 'Warning');
                return;
           end
            brightened_image = imadd(app.original_image, 50);

            imshow(brightened_image, 'Parent', app.UIAxes_2); 
        end

        % Button pushed function: BarrelDistortionButton
        function BarrelDistortionButtonPushed(app, event)
            if isempty(app.original_image)
                msgbox('No image loaded.', 'Error', 'Warning');
                return;
            end
        
            % Defining parameters for the barrel distortion
            k1 = 0.5; % Radial distortion coefficient
            k2 = 0.5; % Tangential distortion coefficient
        
            % Getting the image size
            [rows, cols, ~] = size(app.original_image);
        
            % Creating a meshgrid of x and y coordinates
            [x, y] = meshgrid(1:cols, 1:rows);
        
            % Converting to normalized coordinates
            x_normalized = (x - (cols + 1) / 2) / ((cols + 1) / 2);
            y_normalized = (y - (rows + 1) / 2) / ((rows + 1) / 2);
        
            % Computing radial distance
            r_squared = x_normalized.^2 + y_normalized.^2;
            r_cubed = r_squared .* sqrt(r_squared);
        
            % Applying distortion
            x_distorted = x_normalized .* (1 + k1 * r_squared + k2 * r_cubed);
            y_distorted = y_normalized .* (1 + k1 * r_squared + k2 * r_cubed);
        
            % Converting back to pixel coordinates
            x_distorted_pixel = (x_distorted * ((cols + 1) / 2)) + (cols + 1) / 2;
            y_distorted_pixel = (y_distorted * ((rows + 1) / 2)) + (rows + 1) / 2;
        
            % Interpolating distorted coordinates to get the distorted image
            distorted_image = zeros(size(app.original_image), 'like', app.original_image);
            for channel = 1:size(app.original_image, 3)
                distorted_image(:,:,channel) = interp2(x, y, double(app.original_image(:,:,channel)), ...
                    x_distorted_pixel, y_distorted_pixel, 'cubic');
            end
            
             % Displaying the distorted image
            imshow(distorted_image, 'Parent', app.UIAxes_2); 
        end

        % Button pushed function: GaussianNoiseButton
        function GaussianNoiseButtonPushed(app, event)
            if isempty(app.original_image)
                msgbox('No image loaded.', 'Error', 'Warning');
                return;
            end
        
            % Reading the value of noise_variance from the EditField
            noise_variance_str = app.EditField.Value;
            
            % Converting the string value of variance to a numeric value
            noise_variance = str2double(noise_variance_str);
        
            % Checking if the entered value is valid
            if isnan(noise_variance) || noise_variance <= 0
                msgbox('Invalid noise variance value.', 'Error', 'Warning');
                return;
            end
        
            % Adding Gaussian noise to the original image
            noisy_image = imnoise(app.original_image, 'gaussian', 0, noise_variance);
        
            % Displaying the noisy image
            imshow(noisy_image, 'Parent', app.UIAxes_2);
        end

        % Button pushed function: EdgeSharpeningButton
        function EdgeSharpeningButtonPushed(app, event)
            if isempty(app.original_image)
                msgbox('No image loaded.', 'Error', 'Warning');
                return;
            end
            
            % Getting the sharpening strength from the slider
            sharpening_strength = app.EdgesharpeningSlider.Value;
            
            % Mapping the slider value to the valid alpha range [0, 1]
            mapped_alpha = sharpening_strength / 100;
            
            % Creating the Laplacian filter with the mapped alpha value
            laplacian_filter = fspecial('laplacian', mapped_alpha);
            
            % Applying the Laplacian filter to the original image
            filtered_image = imfilter(app.original_image, laplacian_filter, 'replicate');
            
            % Combining the original image with the filtered image to achieve sharpening
            sharpened_image = app.original_image - filtered_image;
            
            % Displaying the sharpened image
            imshow(sharpened_image, 'Parent', app.UIAxes_2);
        end

        % Button pushed function: SaveProcessedImageButton
        function SaveProcessedImageButtonPushed(app, event)
            if isempty(app.UIAxes_2.Children)
                msgbox('No processed image to save.', 'Error', 'Warning');
                return;
            end

            [file, path] = uiputfile({'*.png', 'PNG Image (*.png)'; '*.jpg', 'JPEG Image (*.jpg)'; '*.bmp', 'Bitmap Image (*.bmp)'; '*.tif', 'TIFF Image (*.tif)'}, 'Save Processed Image');
            
            % Reopening app window because it minimizes after image saving
            figure(app.UIFigure);

            % Checking if the user canceled the operation
            if isequal(file, 0) || isequal(path, 0)
                return;
            end
        
            % Getting the processed image from UIAxes_2 and writinf the
            % image to selected location
            processed_image = app.UIAxes_2.Children.CData;
            full_file_path = fullfile(path, file);
            imwrite(processed_image, full_file_path);
        
            % Displaying a success message
            msgbox('Processed image saved successfully.', 'Success');
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            app.UIAxes.XTick = [];
            app.UIAxes.XTickLabel = '';
            app.UIAxes.YTick = [];
            app.UIAxes.YTickLabel = '';
            app.UIAxes.ZTick = [];
            app.UIAxes.ZTickLabel = '';
            app.UIAxes_2 = uiaxes(app.UIFigure);
            app.UIAxes_2.XTick = [];
            app.UIAxes_2.XTickLabel = '';
            app.UIAxes_2.YTick = [];
            app.UIAxes_2.YTickLabel = '';
            app.UIAxes_2.ZTick = [];
            app.UIAxes_2.ZTickLabel = '';
            app.ConvertToGreyscaleButton = uibutton(app.UIFigure, 'push');
            app.ConvertToGreyscaleButton.ButtonPushedFcn = createCallbackFcn(app, @ConvertToGreyscaleButtonPushed, true);
            app.ConvertToGreyscaleButton.Text = 'Convert To Greyscale';
            app.BrightnessIncreaseButton = uibutton(app.UIFigure, 'push');
            app.BrightnessIncreaseButton.ButtonPushedFcn = createCallbackFcn(app, @BrightnessIncreaseButtonPushed, true);
            app.BrightnessIncreaseButton.Text = 'Brightness Increase';
            app.BarrelDistortionButton = uibutton(app.UIFigure, 'push');
            app.BarrelDistortionButton.ButtonPushedFcn = createCallbackFcn(app, @BarrelDistortionButtonPushed, true);
            app.BarrelDistortionButton.Text = 'Barrel Distortion';
            app.GaussianNoiseButton = uibutton(app.UIFigure, 'push');
            app.GaussianNoiseButton.ButtonPushedFcn = createCallbackFcn(app, @GaussianNoiseButtonPushed, true);
            app.GaussianNoiseButton.Text = 'Gaussian Noise';
            app.EditField = uieditfield(app.UIFigure, 'text');
            app.EdgesharpeningSlider = uislider(app.UIFigure);
            app.EdgesharpeningSlider.MajorTicks = [0 5 10 15 20 25 30 35 40 45 50 55 60 65 70 75 80 85 90 95 100];
            app.EdgeSharpeningButton = uibutton(app.UIFigure, 'push');
            app.EdgeSharpeningButton.ButtonPushedFcn = createCallbackFcn(app, @EdgeSharpeningButtonPushed, true);
            app.EdgeSharpeningButton.Text = 'Edge Sharpening';
            app.OrignalimageLabel = uilabel(app.UIFigure);
            app.OrignalimageLabel.Text = 'Orignal image';
            app.ProcessedimageLabel = uilabel(app.UIFigure);
            app.ProcessedimageLabel.Text = 'Processed image';
            app.SaveProcessedImageButton = uibutton(app.UIFigure, 'push');
            app.SaveProcessedImageButton.ButtonPushedFcn = createCallbackFcn(app, @SaveProcessedImageButtonPushed, true);
            app.SaveProcessedImageButton.Text = 'Save Processed Image';
            app.BrowseImageButton = uibutton(app.UIFigure, 'push');
            app.BrowseImageButton.ButtonPushedFcn = createCallbackFcn(app, @BrowseImageButtonPushed, true);
            app.BrowseImageButton.Text = 'Browse Image';
            app.enternoisevariancevalueLabel = uilabel(app.UIFigure);
            app.enternoisevariancevalueLabel.Text = 'enter noise variance value';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = image_processor_app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end